classdef app1dg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button_2                 matlab.ui.control.Button
        inicio                   matlab.ui.control.Button
        foraaplicada             matlab.ui.control.Slider
        foraaplicadaSliderLabel  matlab.ui.control.Label
        animacao                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ForcaAplicada = 0 
        AnimacaoAtiva = true
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: foraaplicada
        function foraaplicadaValueChanged(app, event)
            app.ForcaAplicada = app.foraaplicada.Value;
        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Button pushed function: inicio
        function inicioPushed2(app, event)
            app.ForcaAplicada=0;
            r=2;
            n=4;
            m=3;
            theta=2*pi/n;
            c_est=0.01;
            c_cin=0.01;
            g=9.8;
            atr_est=c_est*g*m;
            atr_cin=c_cin*g*m;
            
            Mrot = [cos(theta), -sin(theta); sin(theta), cos(theta)];
            V = zeros(2,n);
            V(:,1) = [r*cos(pi/4); r*sin(pi/4)];
            for i = 1:n
                V(:,i+1) = Mrot * V(:,i);
            end
            
        
            cla(app.animacao)
            h = patch(app.animacao, V(1,:), V(2,:), 'b', 'FaceAlpha', 1);
            xlim(app.animacao, [-10 10])
            ylim(app.animacao, [-10 10])


            x = 0;
            v = 0;
            dt = 0.05;
            while app.AnimacaoAtiva 
                fc = app.ForcaAplicada;
                if v==0
                    if abs(fc)<=atr_est
                        F=0;
                    else
                        if fc>0
                            F=fc-atr_cin;
                        elseif fc<0
                            F=fc+atr_cin;
                        end
                    end
                else
                    if abs(fc)<=atr_est
                        while v>0
                            F=-atr_cin;
                        end
                    else
                        if fc>0
                            F=fc-atr_cin;
                        elseif fc<0
                            F=fc+atr_cin;
                        end
                    end
                end


                Ac = F / m;
                v = v + Ac * dt;
                x = x + v * dt;
                set(h, 'XData', V(1,:) + x, 'YData', V(2,:));
                drawnow limitrate
                pause(0.01)
                
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.AnimacaoAtiva = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.animacao = uiaxes(app.UIFigure);
            xlabel(app.animacao, 'X')
            ylabel(app.animacao, 'Y')
            zlabel(app.animacao, 'Z')
            app.foraaplicadaSliderLabel = uilabel(app.UIFigure);
            app.foraaplicadaSliderLabel.Text = 'força aplicada';
            app.foraaplicada = uislider(app.UIFigure);
            app.foraaplicada.Limits = [-5 5];
            app.foraaplicada.ValueChangedFcn = createCallbackFcn(app, @foraaplicadaValueChanged, true);
            app.inicio = uibutton(app.UIFigure, 'push');
            app.inicio.ButtonPushedFcn = createCallbackFcn(app, @inicioPushed2, true);
            app.inicio.Text = 'inicio';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1dg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end