classdef atrito04 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Lamp                            matlab.ui.control.Lamp
        InclinaodoPlanoEditField        matlab.ui.control.NumericEditField
        InclinaodoPlanoEditFieldLabel   matlab.ui.control.Label
        ForasCheckBox                   matlab.ui.control.CheckBox
        MassadoBlocoEditField           matlab.ui.control.NumericEditField
        MassadoBlocoEditFieldLabel      matlab.ui.control.Label
        AceleraoGravticaEditField       matlab.ui.control.NumericEditField
        AceleraoGravticaEditFieldLabel  matlab.ui.control.Label
        cinticoEditField                matlab.ui.control.NumericEditField
        cinticoLabel                    matlab.ui.control.Label
        estticoEditField                matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        foraaplicada                    matlab.ui.control.Slider
        foraaplicadaSliderLabel         matlab.ui.control.Label
        inicio                          matlab.ui.control.Button
        animacao                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ForcaAplicada = 0
        AnimacaoAtiva = true
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: foraaplicada
        function foraaplicadaValueChanged(app, event)
            app.ForcaAplicada = app.foraaplicada.Value;
        end

        % Button pushed function: inicio
        function inicioPushed2(app, event)
            app.Lamp.Color = 'green';
            % A- bloco de cima B-bloco de baixo C-chão
            app.ForcaAplicada=0;
            rA=5;
            rB=5;
            nA=4;
            nB=4;
            mA=1; %massa do bloco de cima
            mB=1; %massa do bloco de baixo
            thetaA=2*pi/nA;
            thetaB=2*pi/nB;
            c_estBC=0.45; %coeficiente de atrito estático chão-bloco
            c_cinBC=0.40; %coeficiente de atrito cinético chão-
            c_estAB=0.45; %coeficiente de atrito estático bloco-bloco
            c_cinAB=0.40; %coeficiente de atrito cinético bloco-bloco
            g=9.8;
            omega=0; %inclinação do plano
            atr_estBC=c_estBC*g*(mB+mA)*cos(omega); %atrito estático chão-bloco
            atr_cinBC=c_cinBC*g*(mB+mA)*cos(omega); %atrito cinético chão-bloco
            atr_estAB=c_estAB*g*mA*cos(omega); %atrito estático bloco-bloco
            atr_cinAB=c_cinAB*g*mA*cos(omega); %atrito cinético bloco-bloco
            MA = [cos(thetaA), -sin(thetaA); sin(thetaA), cos(thetaA)];
            MB = [cos(thetaB), -sin(thetaB); sin(thetaB), cos(thetaB)];
            M_inc = [cos(omega), -sin(omega); sin(omega), cos(omega)]; %matriz da inclinação do bloco
            VA = zeros(2,nA+1);
            VA(:,1) = [rA*cos(pi/nA); rA*sin(pi/nA)];
            for i = 1:nA
                VA(:,i+1) = MA * VA(:,i);
            end
            VB = zeros(2,nB+1);
            VB(:,1) = [rB*cos(pi/nB); rB*sin(pi/nB)];
            for i = 1:nB
                VB(:,i+1) = MB * VB(:,i);
            end
            VA=M_inc*VA; %inclinação do bloco A
            VB=M_inc*[2 0;0 1]*VB; %inclinação do bloco B
            cla(app.animacao)
            x_chao = linspace(-200, 200, 400);
            y_chao = tan(omega) * x_chao;
            x_patch = [x_chao, fliplr(x_chao), x_chao(1)];
            y_patch = [y_chao, repmat(-50, 1, length(x_chao)), y_chao(1)];
            patch(app.animacao, x_patch, y_patch, [0.5 0.3 0.1], 'EdgeColor', 'none');
            hA = patch(app.animacao, VA(1,:), VA(2,:), 'r', 'FaceAlpha', 1);
            hB = patch(app.animacao, VB(1,:), VB(2,:), 'b', 'FaceAlpha', 1);
            xlim(app.animacao, [-50 50])
            ylim(app.animacao, [-50 50])
            axis(app.animacao, 'equal');
            xA = 0;
            
            vA=0;
            xB=0;
            
            vB = 0;
            dt = 0.005;

            Px=(mA+mB)*g*sin(omega);
            
            while app.AnimacaoAtiva
                fc = app.ForcaAplicada;
                if abs(vB)<0.0001
                    if fc==0
                        if Px>atr_estBC
                            F=atr_cinBC-Px;
                        else
                            F=0;
                        end
                    elseif fc<0 && abs(fc)+Px>atr_estBC || fc>0 && abs(fc)>atr_estBC+Px
                        F=fc-(fc/abs(fc))*atr_cinBC-Px;
                    end
                else
                    F=fc-Px-(vB/abs(vB))*atr_cinBC;
                end
               
                Ac = F / (mA+mB);
                vB = vB + Ac * dt;
                xB = xB + vB * dt;
                yB = tan(omega)*xB;


                vA = vA + Ac * dt;
                xA = xA + vA * dt;
                yA = tan(omega)*xA;


                if sqrt((yB-yA)^2+(xB-xA)^2)>rB
                    app.Lamp.Color = 'red';
                    break;
                end

                set(hA, 'XData', VA(1,:) + xA-2*rB*cos(pi/nB)*sin(omega), 'YData', VA(2,:)+yA+2*rB*sin(pi/nB)*cos(omega));
                set(hB, 'XData', VB(1,:) + xB, 'YData', VB(2,:)+yB);
                drawnow limitrate
                pause(0.01)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.animacao = uiaxes(app.UIFigure);
            app.animacao.XAxisLocation = 'origin';
            app.animacao.XTick = [];
            app.animacao.YTick = [];
            app.inicio = uibutton(app.UIFigure, 'push');
            app.inicio.ButtonPushedFcn = createCallbackFcn(app, @inicioPushed2, true);
            app.inicio.Text = 'inicio';
            app.foraaplicadaSliderLabel = uilabel(app.UIFigure);
            app.foraaplicadaSliderLabel.Text = 'força aplicada';
            app.foraaplicada = uislider(app.UIFigure);
            app.foraaplicada.Limits = [-100 100];
            app.foraaplicada.ValueChangedFcn = createCallbackFcn(app, @foraaplicadaValueChanged, true);
            app.foraaplicada.Step = 0.001;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'μ (estático)';
            app.estticoEditField = uieditfield(app.UIFigure, 'numeric');
            app.cinticoLabel = uilabel(app.UIFigure);
            app.cinticoLabel.Text = 'μ (cinético)';
            app.cinticoEditField = uieditfield(app.UIFigure, 'numeric');
            app.AceleraoGravticaEditFieldLabel = uilabel(app.UIFigure);
            app.AceleraoGravticaEditFieldLabel.Text = 'Aceleração Gravítica';
            app.AceleraoGravticaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassadoBlocoEditFieldLabel = uilabel(app.UIFigure);
            app.MassadoBlocoEditFieldLabel.Text = 'Massa do Bloco';
            app.MassadoBlocoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForasCheckBox = uicheckbox(app.UIFigure);
            app.ForasCheckBox.Text = 'Forças';
            app.InclinaodoPlanoEditFieldLabel = uilabel(app.UIFigure);
            app.InclinaodoPlanoEditFieldLabel.Text = 'Inclinação do Plano';
            app.InclinaodoPlanoEditField = uieditfield(app.UIFigure, 'numeric');
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = atrito04

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end