classdef atrito04_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Lamp                  matlab.ui.control.Lamp
        ForasCheckBox         matlab.ui.control.CheckBox
        foraaplicada          matlab.ui.control.Slider
        ForaaplicadaemBLabel  matlab.ui.control.Label
        inicio                matlab.ui.control.Button
        animacao              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ForcaAplicada = 0
        AnimacaoAtiva = true
        aceleracao_cmd = 0
    end
    
    properties (Access = public)
        MassaB
        MassaA
        cof_estAB
        cof_estBC
        cof_cinAB
        cof_cinBC 
        ag
        inc
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: foraaplicada
        function foraaplicadaValueChanged(app, event)
            app.ForcaAplicada = app.foraaplicada.Value;
            
        end

        % Button pushed function: inicio
        function inicioPushed2(app, event)
            % A- bloco de cima B-bloco de baixo C-chão
            app.Lamp.Color = 'green';
            app.ForcaAplicada=0;
            mA=app.MassaA;
            mB=app.MassaB;
            rA=5;
            rB=5;
            nA=4;
            nB=4;
            thetaA=2*pi/nA;
            thetaB=2*pi/nB;
            c_estBC=app.cof_estBC; %coeficiente de atrito estático chão-bloco
            c_cinBC=app.cof_cinBC; %coeficiente de atrito cinético chão-
            c_estAB=app.cof_estAB; %coeficiente de atrito estático bloco-bloco
            c_cinAB=app.cof_cinAB; %coeficiente de atrito cinético bloco-bloco
            g=app.ag;
            omega=app.inc; %inclinação do plano
            atr_estBC=c_estBC*g*(mB+mA)*cos(omega); %atrito estático chão-bloco
            atr_cinBC=c_cinBC*g*(mB+mA)*cos(omega); %atrito cinético chão-bloco
            atr_estAB=c_estAB*g*mA*cos(omega); %atrito estático bloco-bloco
            atr_cinAB=c_cinAB*g*mA*cos(omega); %atrito cinético bloco-bloco
            MA = [cos(thetaA), -sin(thetaA); sin(thetaA), cos(thetaA)];
            MB = [cos(thetaB), -sin(thetaB); sin(thetaB), cos(thetaB)];
            M_inc = [cos(omega), -sin(omega); sin(omega), cos(omega)]; %matriz da inclinação do bloco
            VA = zeros(2,nA+1);
            VA(:,1) = [rA*cos(pi/nA); rA*sin(pi/nA)];
            for i = 1:nA
                VA(:,i+1) = MA * VA(:,i);
            end
            VB = zeros(2,nB+1);
            VB(:,1) = [rB*cos(pi/nB); rB*sin(pi/nB)];
            for i = 1:nB
                VB(:,i+1) = MB * VB(:,i);
            end
            VA=M_inc*VA; %inclinação do bloco A
            VB=M_inc*[2 0;0 1]*VB; %inclinação do bloco B
            cla(app.animacao)
            x_chao = linspace(-200, 200, 400);
            y_chao = tan(omega) * x_chao;
            x_patch = [x_chao, fliplr(x_chao), x_chao(1)];
            y_patch = [y_chao, repmat(-50, 1, length(x_chao)), y_chao(1)];
            patch(app.animacao, x_patch, y_patch, [0.5 0.3 0.1], 'EdgeColor', 'none');
            hA = patch(app.animacao, VA(1,:), VA(2,:), 'r', 'FaceAlpha', 1);
            hB = patch(app.animacao, VB(1,:), VB(2,:), 'b', 'FaceAlpha', 1);
            xlim(app.animacao, [-50 50])
            ylim(app.animacao, [-50 50])
            axis(app.animacao, 'equal');
            xA = 0;


            vA=0;
            xB=0;

            vB = 0;
            dt = 1/20;

            Pxa = (mA) * g * sin(omega);
            Pxb = (mB) * g * sin(omega);                                            %componente em x do peso do conjunto

            t0=tic;
            i=1;
            
            while app.AnimacaoAtiva
                
                fc = app.ForcaAplicada;
                %vamos usar a lógica que eu tinha inicialmente, ou seja
                %começamos por admitir que o regime estático e assim as
                %acelarações são iguais

                v_rel = vB-vA;
                
                
                if abs(fc-Pxa - Pxb)< atr_estBC && abs(vB)<0.0001
                    Aa = 0;
                    Ab = 0;

                    F_tentativa_A = -Pxa;
                    if abs(F_tentativa_A)<= atr_estAB
                        Aa = 0;
                    else
                        Aa = (-Pxa + atr_cinAB) / mA;
                    end
                    
                else
                    f_atrito_chao = sign(vB)*atr_cinBC;
                    if vB == 0
                        f_atrito_chao = sign(fc-Pxa -Pxb)*atr_cinBC;
                    end


                    if abs(v_rel) < 0.0001
                        a = (fc-f_atrito_chao-Pxa-Pxb)/(mA + mB);
                        
                        %atrito necessário para acelarar a
                        fnec = mA*a + Pxa;
                        if v_rel == 0
                            dir_rel = sign(fc);
                        else
                            dir_rel = sign(v_rel);
                        end
    
                        if abs(fnec) <= atr_estAB
                            Ab = a;
                            Aa = a;
                        else
                            fAB = atr_cinAB * dir_rel;
                            Aa = (fAB-Pxa)/mA;
                            Ab = (fc-f_atrito_chao-fAB - Pxb)/mB;
                        end
                    else
                        fAB = atr_cinAB * sign(v_rel);
                        Aa = (fAB-Pxa)/mA;
                        Ab = (fc-f_atrito_chao- fAB - Pxb)/mB;
                    end                
                end


                vA = vA + Aa * dt;
                vB = vB + Ab * dt;
                xA = xA + vA * dt;
                xB = xB + vB * dt;
                yB = tan(omega) * xB;
                yA = tan(omega) * xA;
                if sqrt((yB-yA)^2+(xB-xA)^2)>rB
                    app.Lamp.Color = 'red';
                    break;
                end
                set(hA, 'XData', VA(1,:) + xA-2*rB*cos(pi/nB)*sin(omega), 'YData', VA(2,:)+yA+2*rB*sin(pi/nB)*cos(omega));
                set(hB, 'XData', VB(1,:) + xB, 'YData', VB(2,:)+yB);
                drawnow 
                
                if toc(t0)<i*dt
                    pause(i*dt-toc(t0))
                end
                i=i+1;
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            mA=app.MassaA;
            mB=app.MassaB;
            
            switch event.Key
                case 'uparrow'
                    app.aceleracao_cmd = app.aceleracao_cmd + 0.5;
                case 'downarrow'
                    app.aceleracao_cmd = app.aceleracao_cmd - 0.5;
                otherwise
                    return;
            end
            app.aceleracao_cmd = max(-10, min(10, app.aceleracao_cmd));
            nova_forca = app.aceleracao_cmd * (mA + mB);
            app.ForcaAplicada = nova_forca;
            if nova_forca >= -50 && nova_forca <= 50
                app.foraaplicada.Value = nova_forca;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.animacao = uiaxes(app.UIFigure);
            app.animacao.XAxisLocation = 'origin';
            app.animacao.XTick = [];
            app.animacao.YTick = [];
            app.inicio = uibutton(app.UIFigure, 'push');
            app.inicio.ButtonPushedFcn = createCallbackFcn(app, @inicioPushed2, true);
            app.inicio.Text = 'inicio';
            app.ForaaplicadaemBLabel = uilabel(app.UIFigure);
            app.ForaaplicadaemBLabel.Text = 'Força aplicada em B';
            app.foraaplicada = uislider(app.UIFigure);
            app.foraaplicada.Limits = [-50 50];
            app.foraaplicada.ValueChangedFcn = createCallbackFcn(app, @foraaplicadaValueChanged, true);
            app.foraaplicada.Step = 0.001;
            app.ForasCheckBox = uicheckbox(app.UIFigure);
            app.ForasCheckBox.Text = 'Forças';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = atrito04_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end