classdef inclinacao_bloco < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InclinaodoPlanoEditField        matlab.ui.control.NumericEditField
        InclinaodoPlanoEditFieldLabel   matlab.ui.control.Label
        ForasCheckBox                   matlab.ui.control.CheckBox
        MassadoBlocoEditField           matlab.ui.control.NumericEditField
        MassadoBlocoEditFieldLabel      matlab.ui.control.Label
        AceleraoGravticaEditField       matlab.ui.control.NumericEditField
        AceleraoGravticaEditFieldLabel  matlab.ui.control.Label
        cinticoEditField                matlab.ui.control.NumericEditField
        cinticoLabel                    matlab.ui.control.Label
        estticoEditField                matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        foraaplicada                    matlab.ui.control.Slider
        foraaplicadaSliderLabel         matlab.ui.control.Label
        inicio                          matlab.ui.control.Button
        animacao                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ForcaAplicada = 0
        AnimacaoAtiva = true
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: foraaplicada
        function foraaplicadaValueChanged(app, event)
            app.ForcaAplicada = app.foraaplicada.Value;
        end

        % Button pushed function: inicio
        function inicioPushed2(app, event)
            app.ForcaAplicada=0;
            r=2;
            n=4;
            m=5;
            theta=2*pi/n;
            c_est=0.1;
            c_cin=0.1;
            g=0.1;
            omega=pi/10;
            Fg=g*m*sin(omega);
            Norm=g*m*cos(omega);
            atr_est=c_est*Norm;
            atr_cin=c_cin*Norm;
            Mrot = [cos(theta), -sin(theta); sin(theta), cos(theta)];
            Mrot2 = [cos(omega), -sin(omega); sin(omega), cos(omega)];
            V = zeros(2,n+1);
            V(:,1) = [r*cos(pi/4); r*sin(pi/4)];
            for i = 1:n
                V(:,i+1) = Mrot * V(:,i);
            end
            V=Mrot2*V;
            cla(app.animacao)
            x_chao = linspace(-200, 200, 400);
            y_chao = tan(omega) * x_chao;

            x_patch = [x_chao, fliplr(x_chao), x_chao(1)];
            y_patch = [y_chao, repmat(-50, 1, length(x_chao)), y_chao(1)];
            %patch(app.animacao, x_patch, y_patch, [0.5 0.3 0.1], 'EdgeColor', 'none');
            h = patch(app.animacao, V(1,:), V(2,:), 'b', 'FaceAlpha', 1);
            xlim(app.animacao, [-50 50]);
            ylim(app.animacao, [-50 50]);
            axis(app.animacao, 'equal');
            x = 0;
            v = 0;
            dt = 0.05;

            while app.AnimacaoAtiva
                fc = app.ForcaAplicada;
                if v==0
                    if fc==0
                        if Fg>atr_est
                            F=-Fg+atr_cin;
                        else
                            F=0;
                        end
                    end
                    if fc<0
                        if abs(fc+Fg)>atr_est
                            F=fc-Fg+atr_cin;
                        else
                            F=0;
                        end
                    end
                    if fc>0
                        if fc>atr_est+Fg
                            F=fc-Fg-atr_cin;
                        else
                            F=0;
                        end
                    end
                else
                    if abs(fc)>atr_cin && fc<0
                        F=fc+atr_cin;
                    end
                    if abs(fc)>atr_cin && fc>0
                        F=fc-atr_cin;

                    end
                    if abs(fc)<=atr_cin
                        F=-(v/abs(v))*atr_cin;
                    end
                end
                Ac = F / m;
                v = v + Ac * dt;
                x = x + v * dt;
                y = tan(omega)*x;

                set(h, 'XData', V(1,:) + x, 'YData', V(2,:)+y);
                drawnow
                pause(0.01)
            end
        end

        % Callback function
        function Button_2Pushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.animacao = uiaxes(app.UIFigure);
            app.animacao.XAxisLocation = 'origin';
            app.animacao.XTick = [];
            app.animacao.YTick = [];
            app.inicio = uibutton(app.UIFigure, 'push');
            app.inicio.ButtonPushedFcn = createCallbackFcn(app, @inicioPushed2, true);
            app.inicio.Text = 'inicio';
            app.foraaplicadaSliderLabel = uilabel(app.UIFigure);
            app.foraaplicadaSliderLabel.Text = 'força aplicada';
            app.foraaplicada = uislider(app.UIFigure);
            app.foraaplicada.Limits = [-5 5];
            app.foraaplicada.ValueChangedFcn = createCallbackFcn(app, @foraaplicadaValueChanged, true);
            app.foraaplicada.Step = 0.001;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'μ (estático)';
            app.estticoEditField = uieditfield(app.UIFigure, 'numeric');
            app.cinticoLabel = uilabel(app.UIFigure);
            app.cinticoLabel.Text = 'μ (cinético)';
            app.cinticoEditField = uieditfield(app.UIFigure, 'numeric');
            app.AceleraoGravticaEditFieldLabel = uilabel(app.UIFigure);
            app.AceleraoGravticaEditFieldLabel.Text = 'Aceleração Gravítica';
            app.AceleraoGravticaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassadoBlocoEditFieldLabel = uilabel(app.UIFigure);
            app.MassadoBlocoEditFieldLabel.Text = 'Massa do Bloco';
            app.MassadoBlocoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForasCheckBox = uicheckbox(app.UIFigure);
            app.ForasCheckBox.Text = 'Forças';
            app.InclinaodoPlanoEditFieldLabel = uilabel(app.UIFigure);
            app.InclinaodoPlanoEditFieldLabel.Text = 'Inclinação do Plano';
            app.InclinaodoPlanoEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inclinacao_bloco

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end