classdef menu1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ValoresDefaultButton     matlab.ui.control.Button
        seguinte                 matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        MassaBEditField          matlab.ui.control.NumericEditField
        MassaBEditFieldLabel     matlab.ui.control.Label
        MassaAEditField          matlab.ui.control.NumericEditField
        MassaAEditFieldLabel     matlab.ui.control.Label
        estticoBCEditField       matlab.ui.control.NumericEditField
        estticoBCEditFieldLabel  matlab.ui.control.Label
        cinticoBCEditField       matlab.ui.control.NumericEditField
        cinticoBCEditFieldLabel  matlab.ui.control.Label
        estticoABEditField       matlab.ui.control.NumericEditField
        estticoABEditFieldLabel  matlab.ui.control.Label
        cinticoABEditField       matlab.ui.control.NumericEditField
        cinticoABEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        i=0 
    end
    
    properties (Access = public)
        MassaB
        MassaA
        cof_estAB
        cof_estBC
        cof_cinAB
        cof_cinBC
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MassaBEditField
        function MassaBEditFieldValueChanged(app, event)
            value = app.MassaBEditField.Value;
            if value>0
                app.i=app.i+1;
            end

        end

        % Value changed function: MassaAEditField
        function MassaAEditFieldValueChanged(app, event)
            value = app.MassaAEditField.Value;
            if value>0
                app.i=app.i+1;
            end
        end

        % Value changed function: estticoBCEditField
        function estticoBCEditFieldValueChanged(app, event)
            value = app.estticoBCEditField.Value;
            if value>0
                app.i=app.i+1;
            end
            if app.i==6
                app.seguinte.Visible='on';
            end
        end

        % Value changed function: cinticoBCEditField
        function cinticoBCEditFieldValueChanged(app, event)
            value = app.cinticoBCEditField.Value;
            if value>0
                app.i=app.i+1;
            end
        end

        % Value changed function: estticoABEditField
        function estticoABEditFieldValueChanged(app, event)
            value = app.estticoABEditField.Value;
            if value>0
                app.i=app.i+1;
            end
        end

        % Value changed function: cinticoABEditField
        function cinticoABEditFieldValueChanged(app, event)
            value = app.cinticoABEditField.Value;
            if value>0
                app.i=app.i+1;
            end
        end

        % Button pushed function: seguinte
        function seguinteButtonPushed(app, event)
            sandbox=atrito04_5;
            sandbox.UIFigure.Visible='off';
            sandbox.MassaB=app.MassaBEditField.Value;
            sandbox.MassaA=app.MassaAEditField.Value;
            sandbox.cof_estAB=app.estticoABEditField.Value;
            sandbox.cof_estBC=app.estticoBCEditField.Value;
            sandbox.cof_cinBC=app.cinticoBCEditField.Value;
            sandbox.cof_cinAB=app.cinticoABEditField.Value;
            delete(app)
            menu2(sandbox)
            
        end

        % Button pushed function: ValoresDefaultButton
        function ValoresDefaultButtonPushed(app, event)
            sandbox=atrito04_5;
            sandbox.UIFigure.Visible='off';
            sandbox.MassaB=2;
            sandbox.MassaA=1;
            sandbox.cof_estAB=0.50;
            sandbox.cof_estBC=0.50;
            sandbox.cof_cinBC=0.45;
            sandbox.cof_cinAB=0.45;
            delete(app)
            menu2(sandbox)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.cinticoABEditFieldLabel = uilabel(app.UIFigure);
            app.cinticoABEditFieldLabel.Text = 'μ cinético A/B';
            app.cinticoABEditField = uieditfield(app.UIFigure, 'numeric');
            app.cinticoABEditField.ValueChangedFcn = createCallbackFcn(app, @cinticoABEditFieldValueChanged, true);
            app.estticoABEditFieldLabel = uilabel(app.UIFigure);
            app.estticoABEditFieldLabel.Text = 'μ estático A/B';
            app.estticoABEditField = uieditfield(app.UIFigure, 'numeric');
            app.estticoABEditField.ValueChangedFcn = createCallbackFcn(app, @estticoABEditFieldValueChanged, true);
            app.cinticoBCEditFieldLabel = uilabel(app.UIFigure);
            app.cinticoBCEditFieldLabel.Text = 'μ cinético B/C';
            app.cinticoBCEditField = uieditfield(app.UIFigure, 'numeric');
            app.cinticoBCEditField.ValueChangedFcn = createCallbackFcn(app, @cinticoBCEditFieldValueChanged, true);
            app.estticoBCEditFieldLabel = uilabel(app.UIFigure);
            app.estticoBCEditFieldLabel.Text = 'μ estático B/C';
            app.estticoBCEditField = uieditfield(app.UIFigure, 'numeric');
            app.estticoBCEditField.ValueChangedFcn = createCallbackFcn(app, @estticoBCEditFieldValueChanged, true);
            app.MassaAEditFieldLabel = uilabel(app.UIFigure);
            app.MassaAEditFieldLabel.Text = 'Massa A';
            app.MassaAEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassaAEditField.ValueChangedFcn = createCallbackFcn(app, @MassaAEditFieldValueChanged, true);
            app.MassaBEditFieldLabel = uilabel(app.UIFigure);
            app.MassaBEditFieldLabel.Text = 'Massa B';
            app.MassaBEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassaBEditField.ValueChangedFcn = createCallbackFcn(app, @MassaBEditFieldValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'imagem_app.png';
            app.seguinte = uibutton(app.UIFigure, 'push');
            app.seguinte.ButtonPushedFcn = createCallbackFcn(app, @seguinteButtonPushed, true);
            app.seguinte.Visible = 'off';
            app.seguinte.Text = 'Seguinte';
            app.ValoresDefaultButton = uibutton(app.UIFigure, 'push');
            app.ValoresDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ValoresDefaultButtonPushed, true);
            app.ValoresDefaultButton.Text = 'Valores Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end