classdef menu2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        lua_2                  matlab.ui.control.Image
        seguinte               matlab.ui.control.Button
        lua                    matlab.ui.control.Image
        personalizada          matlab.ui.control.NumericEditField
        PersonalizadaLabel     matlab.ui.control.Label
        LuaButton              matlab.ui.control.Button
        TerraButton            matlab.ui.control.Button
        AceleraoGravticaLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        ag % Description
    end
    
    properties (Access = private)
        sandbox % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sandbox_r)
            app.sandbox=sandbox_r;
        end

        % Value changed function: personalizada
        function personalizadaValueChanged(app, event)
            value = app.personalizada.Value;
            if value>0
                app.ag=app.personalizada.Value;
                app.sandbox.ag=app.ag;
                menu3(app.sandbox)
                delete(app)
            end
        end

        % Button pushed function: TerraButton
        function TerraButtonPushed(app, event)
            app.ag=9.81;
            app.sandbox.ag=app.ag;
            menu3(app.sandbox)
            delete(app)
            
        end

        % Button pushed function: LuaButton
        function LuaButtonPushed(app, event)
            app.ag=1.62;
            app.sandbox.ag=app.ag;
            menu3(app.sandbox)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AceleraoGravticaLabel = uilabel(app.UIFigure);
            app.AceleraoGravticaLabel.Text = 'Aceleração Gravítica';
            app.TerraButton = uibutton(app.UIFigure, 'push');
            app.TerraButton.ButtonPushedFcn = createCallbackFcn(app, @TerraButtonPushed, true);
            app.TerraButton.Text = 'Terra';
            app.LuaButton = uibutton(app.UIFigure, 'push');
            app.LuaButton.ButtonPushedFcn = createCallbackFcn(app, @LuaButtonPushed, true);
            app.LuaButton.Text = 'Lua';
            app.PersonalizadaLabel = uilabel(app.UIFigure);
            app.PersonalizadaLabel.Text = 'Personalizada';
            app.personalizada = uieditfield(app.UIFigure, 'numeric');
            app.personalizada.ValueChangedFcn = createCallbackFcn(app, @personalizadaValueChanged, true);
            app.lua = uiimage(app.UIFigure);
            app.lua.ImageSource = fullfile(pathToMLAPP, 'Relatório', 'a_verdade_sobre_a_lua.png');
            app.seguinte = uibutton(app.UIFigure, 'push');
            app.seguinte.Visible = 'off';
            app.seguinte.Text = 'Seguinte';
            app.lua_2 = uiimage(app.UIFigure);
            app.lua_2.ImageSource = fullfile(pathToMLAPP, 'Relatório', 'aquilo_que_a_media_liberal_nao_conta.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end