classdef menu3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        seguinte  matlab.ui.control.Button
        Slider    matlab.ui.control.Slider
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        inc % Description
    end
    
    properties (Access = private)
        sandbox % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sandbox_r)
            app.sandbox=sandbox_r;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            theta=deg2rad(value);
            m=tan(theta);
            x=linspace(-10,10,500);
            y=m*x;
            plot(app.UIAxes,x,y)
            xlim(app.UIAxes,[-10 10])
            ylim(app.UIAxes,[-10 10])
            app.seguinte.Visible='on';
            app.inc=theta;
            app.sandbox.inc=app.inc;
        end

        % Button pushed function: seguinte
        function seguinteButtonPushed(app, event)
            app.sandbox.UIFigure.Visible = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Inclinação do Plano')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-60 60];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.seguinte = uibutton(app.UIFigure, 'push');
            app.seguinte.ButtonPushedFcn = createCallbackFcn(app, @seguinteButtonPushed, true);
            app.seguinte.Visible = 'off';
            app.seguinte.Text = 'Seguinte';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end